classdef SampSampGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        fileToolbar     matlab.ui.container.Menu
        saveFcn         matlab.ui.container.Menu
        loadFcn         matlab.ui.container.Menu
        exitFcn         matlab.ui.container.Menu
        openFile        matlab.ui.control.Button
        listbox         matlab.ui.control.ListBox
        TriggerPanel    matlab.ui.container.Panel
        stopTrigger     matlab.ui.container.ButtonGroup
        MintrigButton   matlab.ui.control.RadioButton
        MaxtrigButton   matlab.ui.control.RadioButton
        trigOrNot       matlab.ui.control.DropDown
        triggerUnit_2   matlab.ui.control.Label
        triggerUnit_1   matlab.ui.control.Label
        startTrigValue  matlab.ui.control.EditField
        startTrigger    matlab.ui.control.Label
        stopTrigValue   matlab.ui.control.EditField
        AnalogInput     matlab.ui.container.ButtonGroup
        a0              matlab.ui.control.CheckBox
        a7              matlab.ui.control.CheckBox
        a6              matlab.ui.control.CheckBox
        a5              matlab.ui.control.CheckBox
        a4              matlab.ui.control.CheckBox
        a3              matlab.ui.control.CheckBox
        a2              matlab.ui.control.CheckBox
        a1              matlab.ui.control.CheckBox
        Save            matlab.ui.container.Panel
        name            matlab.ui.control.EditField
        openFolder      matlab.ui.control.Button
        saveDir         matlab.ui.control.Label
        startPanel      matlab.ui.container.Panel
        Device          matlab.ui.control.DropDown
        text12          matlab.ui.control.Label
        text11          matlab.ui.control.Label
        text10          matlab.ui.control.Label
        delSam          matlab.ui.control.EditField
        text9           matlab.ui.control.Label
        stopDAQ         matlab.ui.control.Button
        sec             matlab.ui.control.EditField
        TimeoutText     matlab.ui.control.Label
        freqText        matlab.ui.control.Label
        freq            matlab.ui.control.EditField
        startDAQ        matlab.ui.control.Button
        triggerPlot     matlab.ui.control.UIAxes
        dataPlot        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Information about the connected DAQ device
        daqConnection = "Dev1"; % Refers to what NI device is being used
        daqType = "ni"; % Name of daqDevice
        DAQ % Handle to connected DAQ hardware object
        analogueInputChoice = [1, 0, 0, 0, 0, 0, 0, 1]; % What is the chosen input source (represents a0 - a7)
        sampFrequency % What frequency is being recorded at
        timeout % How long, in seconds, will it take to timeout the recording
        sampleDelay % Delay how many samples before recording data
        
        % Options relating to trigger values for DAQ
        useTrigger
        startTrigVal
        stopTrigVal
        trigActive
        trigMoment
        aboveUnder = true % Change to soething else, confusing

        % State of the DAQ device during data acquisition
        currentState = 'Acquisition.ReadyForCapture';

        % Bounderies for the plotwindows
        axisXmin = 0; 
        axisXmax = 11;
        axisYmin = 0;
        axisYmax = 3;  

        delaySamples= 0;     % How many samples to store from before the start trigger
        countOnOff = 2;      % Counter that makes sure that start button is green when sampling end red when not(that it dosent flicker)        
        subPlot = '1:5:end'; % Plots every 5th sample

        % Define properties regarding saving data
        files                              % List of individual .mat files containing data
        fileNameData = 'data_block';       % Each data block gets a name data_block1,...,data_block20,....
        fileNameTime = 'ticktimes_block';  % To each block of data a corresponding time stamp is saved with the name ticktimes_block1,....tcktimes_block20,....
        dataBlock = 'data_block';          % Each data block gets a name data_block1,...,data_block20,....
        fileCounter = 1;                   % Name counter of data files ex. data_block1, data_block2        
        counter % Description

        DataFIFOBuffer
        TimestampsFIFOBuffer
        CaptureStartMoment

        Data
        Timestamps

        % Variables about viewing the data live, needs to be changable!!
        ViewTimeWindow = 3;
        TriggerDelay = app.TriggerDelayEditField.Value;
    end
    
    methods (Access = private)
        function scansAvailable_Callback(app, src, ~)
        % scansAvailable_Callback Executes on DAQ ScansAvailable event
        % This callback function gets executed periodically as more data is acquired by the daqDevice.
        % This callback is setup in the script 'configureDAQ.m' 
        
            if ~isvalid(app)
                return
            end
            
            % Continuous acquisition data and timestamps are stored in FIFO data buffers
            % Calculate required buffer size -- this should be large enough to accomodate the
            % the data required for the live view time window and the data for the requested
            % capture duration.
            app.TriggerDelay = app.TriggerDelayEditField.Value;
            
            [data,timestamps] = read(src, src.ScansAvailableFcnCount, 'OutputFormat','Matrix');
            
            % Store continuous acquisition data in FIFO data buffers
            app.TimestampsFIFOBuffer = storeDataInFIFO(app.TimestampsFIFOBuffer, app.BufferSize, timestamps);
            app.DataFIFOBuffer = storeDataInFIFO(app.DataFIFOBuffer, app.BufferSize, data(:,1));
            
            % Update live plot data
            samplesToPlot = min([round(app.ViewTimeWindow * src.Rate), size(app.DataFIFOBuffer,1)]);
            firstPoint = size(app.DataFIFOBuffer, 1) - samplesToPlot + 1;
            if samplesToPlot > 1
                app.triggerPlot.XLim = [app.TimestampsFIFOBuffer(firstPoint), app.TimestampsFIFOBuffer(end)];
            end
            set(app.triggerPlot, 'XData', app.TimestampsFIFOBuffer(firstPoint:end), ...
                'YData', app.DataFIFOBuffer(firstPoint:end));
            
            
            % If capture is requested, analyze latest acquired data until a trigger
            % condition is met. After enough data is acquired for a complete capture,
            % as specified by the capture duration, extract the capture data from the
            % data buffer and save it to a base workspace variable.
            
            % For trigger detection, store previous and current ScansAvailable callback data and timestamps
            if isempty(app.Timestamps)
                app.Data = data(:,1);
                app.Timestamps = timestamps;
            else
                app.Data = [app.Data(end,1); data(:,1)];
                app.Timestamps = [app.Timestamps(end); timestamps];
            end
            
            % App state control logic 
            switch app.currentState
                case 'Acquisition.Buffering'
                    % Buffering pre-trigger data
                    if isEnoughDataBuffered(app.TimestampsFIFOBuffer, app.sampleDelay) && app.useTrigger
                        % Depending if user wants to employ a software
                        % trigger or not, change state
                        app.currentState = 'Capture.CapturingData';
                        if app.useTrigger; app.currentState = 'Capture.LookingForTrigger'; end
                    end
                case 'Capture.LookingForTrigger'
                    % Looking for trigger event in the latest data
                    detectTrigger(app)
                    if app.TrigActive
                        app.currentState = 'Capture.CapturingData';
                    end
                case 'Capture.CapturingData'
                    % Capturing data
                    % Not enough acquired data to cover capture timespan during this ScansAvailable callback execution
                    timeoutResult = (app.TimestampsFIFOBuffer(end,1)-app.CaptureStartMoment) > app.CaptureDuration;
                    if timeoutResult % || trigger conditions met
                        app.currentState = 'Capture.CaptureComplete';
                    end
                case 'Capture.CaptureComplete'
                    % Acquired enough data to complete capture of specified duration
                    completeCapture(app)
                    app.currentState = 'Acquisition.ReadyForCapture';
            end
        end

        function detectTrigger(app)
        %detectTrigger Detects trigger condition and updates relevant app properties
        % Updates TrigActive, TrigMoment, and CaptureStartMoment app properties
            
            trigConfig.Channel = 1; % Double check this
            trigConfig.Level = app.startTrigValue.Value;
            trigConfig.Condition = 'Rising';
            [app.TrigActive, app.TrigMoment] = ...
                trigDetect(app, app.Timestamps, app.Data, trigConfig);
            app.CaptureStartMoment = app.TrigMoment + app.TriggerDelay;
        end

        function completeCapture(app)
        %completeCapture Saves captured data to workspace variable and plots it
            
            % Find index of first sample in data buffer to be captured
            firstSampleIndex = find(app.TimestampsFIFOBuffer >= app.CaptureStartMoment, 1, 'first');
            
            % Find index of last sample in data buffer that complete the capture
            lastSampleIndex = firstSampleIndex + round(app.timeout * app.sampFrequency);
            if isempty(firstSampleIndex) || isempty(lastSampleIndex) || lastSampleIndex > size(app.TimestampsFIFOBuffer, 1)
                % Something went wrong
                % Abort capture
                % Fill this with more sampsamp related warnings
                uialert(fig, 'Could not complete capture.', 'Capture error');
                return
            end
            
            % Extract capture data and shift timestamps so that 0 corresponds to the trigger moment
            captureData = app.DataFIFOBuffer(firstSampleIndex:lastSampleIndex);
            captureTimestamps = app.TimestampsFIFOBuffer(firstSampleIndex:lastSampleIndex, :) - app.TrigMoment;
            
            % Update captured data plot (one line for each acquisition channel)
            set(app.CapturePlotLine, 'XData', app.CaptureTimestamps, 'YData', app.CaptureData);
            app.CaptureDataAxes.XLim = [min(app.CaptureTimestamps), max(app.CaptureTimestamps)];
            
            % Save captured data to user selected folder
            % For simplicity, validation of user input and checking whether a variable
            % with the same name already exists are not addressed in this example.
            % Get the variable name from UI text input
            stop(hObject)
    
            fileNameMat = ["Data_Block_" app.counter];
    
            save([ samHandles.folderDir '\' fileNameMat '.mat']...
                ,captureData,captureTimestamps);
    
            %---Save the handle structure-----
            app.counter = c + 1;
            %---------------------------------
    
            %---start the daq-object again-----
            try
                % Reset these variables to free up memory
                app.Data = [];
                app.DataFIFOBuffer = [];
                app.Timestamps = [];
                app.TimestampsFIFOBuffer = [];
                start(app.DAQ)
            catch
                disp 'Timed out';
            end
            %----------------------------------     
        end
        
        function daqRealTimePlotCallback(~, hObject, ~, ~, samHandles)
            % hObject    handle to daqrealtimeplot (see GCBO)
            size = hObject.SamplesAvailable;
            if hObject.SamplesAvailable > 160
            
            
                lastSamps=peekdata(hObject, samHandles.stopSize);
                data=peekdata(hObject,size);
            
                if samHandles.aboveUnder
                    test = max(lastSamps);
                    test = test(1);
                else
                    test = min(lastSamps);
                    test = test(1);
                end
                if test<samHandles.stopTrig
                    % stop(hObject)
                    % c = app.counter;
                    % samps = hObject.SamplesAvailable;
                    % [data,~,~]=getdata(hObject,samps);
                    % 
                    % samHandles.fileNameData = [samHandles.dataBlock num2str(c)];
                    % %%%value to ticktimes
                    % 
                    % fileNameData = [samHandles.dataBlock num2str(c)];
                    % fileNameTime = [samHandles.fileNameTime num2str(c)];
                    % 
                    % eval([sprintf(fileNameData) '=data'';']);
                    % eval([sprintf(fileNameTime) '=ticktimes_block;']);
                    % 
                    % save([ samHandles.folderDir '\' samHandles.fileNameMat '.mat']...
                    %     ,fileNameData,fileNameTime,'-append');
                    % 
                    % %---Save the handle structure-----
                    % app.counter = c + 1;
                    % %---------------------------------
                    % 
                    % %---start the daq-object again-----
                    % try
                    %     start(hObject)
                    % catch
                    %     disp 'Timed out';
                    % end
                    %----------------------------------
                end
            
                N=length(data(:,1));
                T=N/samHandles.freq;
                x=linspace(0,T,N);
            
                if T<11
                    cla(samHandles.plotFigTrigAxis)
                    axis(samHandles.plotFigTrigAxis,[0 11 0 4])
                    hold (samHandles.plotFigTrigAxis,'on')
            
                    plot(samHandles.plotFigTrigAxis,x,data(sprintf(samHandles.subPlot),1));
                    hold (samHandles.plotFigTrigAxis,'off')
                else
                    x2=linspace(T-10,T,10*samHandles.freq+1);
                    plot(samHandles.plotFigTrigAxis,x2,data(end-samHandles.freq*10:end,1));
                end
            
                if T<11
                    cla(samHandles.plotFigDataAxis);
                    axis(samHandles.plotFigDataAxis,[0 11 0 0.2])
                    hold (samHandles.plotFigDataAxis,'on')
                    axis (samHandles.plotFigDataAxis,'auto y');
                    plot(samHandles.plotFigDataAxis,x,data(sprintf(samHandles.subPlot),2));
                    hold (samHandles.plotFigDataAxis,'off')
                else
                    plot(samHandles.plotFigDataAxis,x2,data(end-samHandles.freq*10:end,2));
                end
            end
        end
        
        function timerCallback(~, ~, ~, handles)
            % handles    structure with handles and user data (see GUIDATA)
            
            % Get default command line output from handles structure
            if ~isempty(daqfind)
                ai = daqfind;
                samHandles = getappdata(0,'samHandles');
                if strcmp(ai.Running, 'On') && samHandles.countOnOff>1
                    disp start
                    set(handles.startDAQ,'ForegroundColor', [0 1 0]);
                    samHandles.countOnOff = 0;
                end
                if strcmp(ai.Running, 'Off')&& samHandles.countOnOff<2
                    samHandles.countOnOff = samHandles.countOnOff + 1;
                    if samHandles.countOnOff>1
                        samHandles.fileNameData = [samHandles.dataBlock];
                
                        fileNameData = [samHandles.dataBlock];
                        fileNameTime = [samHandles.fileNameTime];
                
                        eval([sprintf(fileNameData) '=data'';']);
                        eval([sprintf(fileNameTime) '=ticktimes_block;']);
                
                        save([ samHandles.folderDir '\' samHandles.fileNameMat '.mat']...
                            ,fileNameData,fileNameTime,'-append');
                
                        disp stop
                        set(handles.startDAQ,'ForegroundColor',[1 0 0]);
                    end
                end
            
            setappdata(0,'samHandles',samHandles);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function sampsamp_OpeningFcn(app, varargin)
            % --- Executes just before sampsamp is made visible.
            uiWindow=gcf;

            % Set access to figure window for easy editing
            setappdata(0,'samsam',uiWindow);
            
            % Delete any existing timer objects to prevent errors
            if ~isempty(timerfind)
                stop(timerfind)
                delete(timerfind)
            end
            
        end

        % Menu selected function: exitFcn
        function exit_Callback(app, event)
            % --- Executes on button press in exit.
            close(gcf)
        end

        % Value changed function: listbox
        function listbox_Callback(app, event)
            % --- Executes on selection change in listbox.
            val = app.listbox.Value;

            if val > 1
                load(app.matFile,app.files(val-1).name)
            end

        end

        % Button pushed function: openFile
        function openFile_Callback(app, event)
            % Loads a user selected .mat file of previously saved data
            [file, folder]= uigetfile('');
            if isequal(file,0) || isequal(folder,0)
                disp('Operation canceled')
            else
                app.matFile = [folder file];
                app.files = whos ('-file', [folder file]);
                storlek = size(app.files);
                N = (storlek(1)-1)/2;
            
                app.listbox.Items = {'load new file'};
                for i = 1:N-3
                    oldString = app.listbox.Items;
                    app.listbox.Items = [oldString(:)' {app.files(i).name}];
                end
            end
        end

        % Button pushed function: openFolder
        function openFolder_Callback(app, event)
            % --- Executes on button press in openFolder.
            
            dir=uigetdir;
            if ~dir==0 % If not cancelled, save user choice
                app.saveDir.Text = dir;
            end
        end

        % Button pushed function: stopDAQ
        function stopDAQ_Callback(app, event)
            % --- Executes on button press in reset.
            
            if ~isempty(app.DAQ)
                stop(app.DAQ);
            end
        end

        % Button pushed function: startDAQ
        function startDAQ_Callback(app, event)
            % --- Executes on button press in startDAQ.
            daqreset;

            % Reset FIFO buffer data
            app.DataFIFOBuffer = [];
            app.TimestampsFIFOBuffer = [];

            % Reset Data and Timestamps
            app.Data = [];
            app.Timestamps = [];
            
            recordStart = datetime("now");
            newFolder = ([app.saveDir.Text '\' recordStart]);
            mkdir(newFolder);
            
            info = '---------Data from sampsamp--------------';
            save([newFolder '\' recordStart '.mat'],'info');
            
            % Reset files variable
            app.files={''};

            % Function to load selected channels and apply daq settings to a new daq object 
            app.DAQ = configureDAQ(app);
            
            % Set counter for datablock
            app.counter = 1;
            
            % Start DAQ device, quit if errors occcur
            try
                start(app.DAQ,'continuous');
                tic
            catch
                disp 'error';
                return
            end

            app.currentState = 'Acquisition.Buffering';
            
            if app.trigOrNot.Value == 0
                % Need to find replacement in modern DAQ toolbox, there was
                % code here to be able to istantly 'getdata' and save the
                % data, time, and 'abstime'
            end
        end

        % Value changed function: trigOrNot
        function trigOrNot_Callback(app, event)
            % --- Executes on selection change in trigOrNot.

            val = app.trigOrNot.Value;
            app.useTrigger = val;
            switch val
                case "With trigger"
                    % Enable trigger options
                    app.startTrigger.Enable = 'On';
                    app.startTrigValue.Enable = 'On';
                    app.triggerUnit_1.Enable = 'On';
                    app.triggerUnit_2.Enable = 'On';
                    app.stopTrigger.Enable = 'On';
                    app.stopTrigValue.Enable = 'On';

                    % Allow user freqs and timeouts
                    app.freq.Enable = 'On';
                    app.freq.Value = num2str(app.sampFrequency);
                    app.sec.Enable = 'On';
                    app.sec.Value = num2str(app.timeout);
                case "No trigger"
                    % Disable trigger options
                    app.startTrigger.Enable = 'Off';
                    app.startTrigValue.Enable = 'Off';
                    app.triggerUnit_1.Enable = 'Off';
                    app.triggerUnit_2.Enable = 'Off';
                    app.stopTrigger.Enable = 'Off';
                    app.stopTrigValue.Enable = 'Off';

                    %Force default values
                    app.freq.Enable = 'Off';
                    app.freq.Value = '40000';
                    app.sec.Enable = 'Off';
                    app.sec.Value = '1800';
            end
        end

        % Menu selected function: saveFcn
        function saveFcnMenuSelected(app, event)
            
        end

        % Menu selected function: loadFcn
        function loadFcnMenuSelected(app, event)
            
        end

        % Value changed function: startTrigValue
        function startTrigValueValueChanged(app, event)
            value = app.startTrigValue.Value;
            app.startTrigVal = value;
        end

        % Value changed function: stopTrigValue
        function stopTrigValueValueChanged(app, event)
            value = app.stopTrigValue.Value;
            app.stopTrigVal = value;
        end

        % Value changed function: freq
        function freqValueChanged(app, event)
            value = app.freq.Value;
            app.sampFrequency = value;
        end

        % Value changed function: sec
        function secValueChanged(app, event)
            value = app.sec.Value;
            app.timeout = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'sampsamp';
            app.figure1.Tag = 'figure1';
            app.fileToolbar = uimenu(app.figure1);
            app.fileToolbar.Text = 'File';
            app.fileToolbar.Tag = 'uimenu1';
            app.saveFcn = uimenu(app.fileToolbar);
            app.saveFcn.MenuSelectedFcn = createCallbackFcn(app, @saveFcnMenuSelected, true);
            app.saveFcn.Text = 'Save Config';
            app.loadFcn = uimenu(app.fileToolbar);
            app.loadFcn.MenuSelectedFcn = createCallbackFcn(app, @loadFcnMenuSelected, true);
            app.loadFcn.Text = 'Load Config';
            app.exitFcn = uimenu(app.fileToolbar);
            app.exitFcn.MenuSelectedFcn = createCallbackFcn(app, @exit_Callback, true);
            app.exitFcn.Text = 'Exit';
            app.dataPlot = uiaxes(app.figure1);
            app.dataPlot.Tag = 'dataPlot';
            app.triggerPlot = uiaxes(app.figure1);
            app.triggerPlot.Tag = 'triggerPlot';
            app.startPanel = uipanel(app.figure1);
            app.startPanel.Title = 'Sampling';
            app.startPanel.Tag = 'startPanel';
            app.startDAQ = uibutton(app.startPanel, 'push');
            app.startDAQ.ButtonPushedFcn = createCallbackFcn(app, @startDAQ_Callback, true);
            app.startDAQ.Tag = 'startDAQ';
            app.startDAQ.Text = 'Start-DAQ';
            app.freq = uieditfield(app.startPanel, 'text');
            app.freq.ValueChangedFcn = createCallbackFcn(app, @freqValueChanged, true);
            app.freq.Tag = 'freq';
            app.freq.Value = '40000';
            app.freqText = uilabel(app.startPanel);
            app.freqText.Tag = 'freqText';
            app.freqText.Text = 'f [Hz]';
            app.TimeoutText = uilabel(app.startPanel);
            app.TimeoutText.Tag = 'TimeoutText';
            app.TimeoutText.Text = 'Timeout';
            app.sec = uieditfield(app.startPanel, 'text');
            app.sec.ValueChangedFcn = createCallbackFcn(app, @secValueChanged, true);
            app.sec.Tag = 'sec';
            app.sec.Value = '1200';
            app.stopDAQ = uibutton(app.startPanel, 'push');
            app.stopDAQ.ButtonPushedFcn = createCallbackFcn(app, @stopDAQ_Callback, true);
            app.stopDAQ.Tag = 'reset';
            app.stopDAQ.Text = 'Stop-DAQ';
            app.text9 = uilabel(app.startPanel);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Delay';
            app.delSam = uieditfield(app.startPanel, 'text');
            app.delSam.Tag = 'delSam';
            app.delSam.Value = '25';
            app.text10 = uilabel(app.startPanel);
            app.text10.Tag = 'text10';
            app.text10.Text = '[Hz]';
            app.text11 = uilabel(app.startPanel);
            app.text11.Tag = 'text11';
            app.text11.Text = '[s]';
            app.text12 = uilabel(app.startPanel);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Samples';
            app.Device = uidropdown(app.startPanel);
            app.Device.Items = {'Dev1', 'Dev2', 'Dev3'};
            app.Device.Tag = 'Device';
            app.Device.Value = 'Dev2';
            app.Save = uipanel(app.figure1);
            app.Save.Title = 'Save Options';
            app.Save.Tag = 'Save';
            app.saveDir = uilabel(app.Save);
            app.saveDir.Tag = 'saveDir';
            app.saveDir.Text = 'E:\Yuri';
            app.openFolder = uibutton(app.Save, 'push');
            app.openFolder.ButtonPushedFcn = createCallbackFcn(app, @openFolder_Callback, true);
            app.openFolder.Tag = 'openFolder';
            app.openFolder.Text = 'Open folder';
            app.name = uieditfield(app.Save, 'text');
            app.name.Tag = 'name';
            app.name.Value = 'TagnameOfFile';
            app.AnalogInput = uibuttongroup(app.figure1);
            app.AnalogInput.Title = 'Analog input';
            app.AnalogInput.Tag = 'AnalogInput';
            app.a1 = uicheckbox(app.AnalogInput);
            app.a1.Tag = 'a1';
            app.a1.Text = 'a1';
            app.a2 = uicheckbox(app.AnalogInput);
            app.a2.Tag = 'a2';
            app.a2.Text = 'a2';
            app.a3 = uicheckbox(app.AnalogInput);
            app.a3.Tag = 'a3';
            app.a3.Text = 'a3';
            app.a4 = uicheckbox(app.AnalogInput);
            app.a4.Tag = 'a4';
            app.a4.Text = 'a4';
            app.a5 = uicheckbox(app.AnalogInput);
            app.a5.Tag = 'a5';
            app.a5.Text = 'a5';
            app.a6 = uicheckbox(app.AnalogInput);
            app.a6.Tag = 'a6';
            app.a6.Text = 'a6';
            app.a7 = uicheckbox(app.AnalogInput);
            app.a7.Tag = 'a7';
            app.a7.Text = 'a7';
            app.a7.Value = true;
            app.a0 = uicheckbox(app.AnalogInput);
            app.a0.Tag = 'a0';
            app.a0.Enable = 'off';
            app.a0.Text = 'a0';
            app.a0.Value = true;
            app.TriggerPanel = uipanel(app.figure1);
            app.TriggerPanel.Title = 'Trigger setings';
            app.TriggerPanel.Tag = 'TriggerPanel';
            app.stopTrigValue = uieditfield(app.TriggerPanel, 'text');
            app.stopTrigValue.ValueChangedFcn = createCallbackFcn(app, @stopTrigValueValueChanged, true);
            app.stopTrigValue.Tag = 'stopTrig';
            app.stopTrigValue.Value = '3.5';
            app.startTrigger = uilabel(app.TriggerPanel);
            app.startTrigger.Tag = 'triggerValue';
            app.startTrigger.Text = 'Start when trig >';
            app.startTrigValue = uieditfield(app.TriggerPanel, 'text');
            app.startTrigValue.ValueChangedFcn = createCallbackFcn(app, @startTrigValueValueChanged, true);
            app.startTrigValue.Tag = 'triggVal';
            app.startTrigValue.Value = '3';
            app.triggerUnit_1 = uilabel(app.TriggerPanel);
            app.triggerUnit_1.Tag = 'text13';
            app.triggerUnit_1.Text = '[V]';
            app.triggerUnit_2 = uilabel(app.TriggerPanel);
            app.triggerUnit_2.Tag = 'text14';
            app.triggerUnit_2.Text = '[V]';
            app.trigOrNot = uidropdown(app.TriggerPanel);
            app.trigOrNot.Items = {'With trigger', 'No trigger'};
            app.trigOrNot.ValueChangedFcn = createCallbackFcn(app, @trigOrNot_Callback, true);
            app.trigOrNot.Tag = 'trigOrNot';
            app.trigOrNot.Value = 'With trigger';
            app.stopTrigger = uibuttongroup(app.TriggerPanel);
            app.stopTrigger.Title = 'Stop when:';
            app.MaxtrigButton = uiradiobutton(app.stopTrigger);
            app.MaxtrigButton.Text = 'Max (trig)  <';
            app.MaxtrigButton.Value = true;
            app.MintrigButton = uiradiobutton(app.stopTrigger);
            app.MintrigButton.Text = 'Min  (trig)  <';
            app.listbox = uilistbox(app.figure1);
            app.listbox.Items = {'Listbox'};
            app.listbox.ValueChangedFcn = createCallbackFcn(app, @listbox_Callback, true);
            app.listbox.Tag = 'listbox';
            app.listbox.Value = 'Listbox';
            app.openFile = uibutton(app.figure1, 'push');
            app.openFile.ButtonPushedFcn = createCallbackFcn(app, @openFile_Callback, true);
            app.openFile.Tag = 'openFile';
            app.openFile.Text = 'Open file';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SampSampGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)sampsamp_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end